classdef EasyBehaviour < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EasyBehaviourUIFigure       matlab.ui.Figure
        Menu_File                   matlab.ui.container.Menu
        Menu_NewExperiment          matlab.ui.container.Menu
        Menu_Quit                   matlab.ui.container.Menu
        Menu_Image                  matlab.ui.container.Menu
        Menu_BrightnessAndContrast  matlab.ui.container.Menu
        Menu_Scale                  matlab.ui.container.Menu
        Menu_Configuration          matlab.ui.container.Menu
        Menu_Peripherals            matlab.ui.container.Menu
        Menu_Preference             matlab.ui.container.Menu
        Menu_Tool                   matlab.ui.container.Menu
        Menu_Tracker                matlab.ui.container.Menu
        Menu_Parameterization       matlab.ui.container.Menu
        Menu_FeatureAnalysis        matlab.ui.container.Menu
        Menu_Plugins                matlab.ui.container.Menu
        Menu_Help                   matlab.ui.container.Menu
        Menu_Language               matlab.ui.container.Menu
        Menu                        matlab.ui.container.Menu
        EnglishMenu                 matlab.ui.container.Menu
        Menu_Document               matlab.ui.container.Menu
        Menu_About                  matlab.ui.container.Menu
        Menu_Update                 matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        Label_2                     matlab.ui.control.Label
        Lamp_Experiment             matlab.ui.control.Lamp
        Label_3                     matlab.ui.control.Label
        Lamp_Equipment              matlab.ui.control.Lamp
        NILabel                     matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
        Label                       matlab.ui.control.Label
        GridLayout_Progress         matlab.ui.container.GridLayout
        Label_ProgressLeft          matlab.ui.control.Label
        Label_Progress              matlab.ui.control.Label
        UIAxes_TaskViewer           matlab.ui.control.UIAxes
    end

    properties (Constant, Hidden)

    end

    properties (Access = private)
        % hardware objects
        camera          % 1-by-1 EBCamera object     
        valves          % 1-by-1 EBValves object
        
    end

    methods (Access = ?HWC)
        function cam = GetCamera(app)
            % share the camera control right
            cam = app.camera;       % handle copy
        end

        function SetCamera(app, cam)
            app.camera = cam;
        end

        function vv = GetValves(app)
            % share the camera control right
            vv = app.valves;        % handle copy
        end
    end

    methods (Access = private)
        function InitOptions(app)

        end

        function InitComponents(app)

        end

        function InitHardwareDevices(app)
            % init camera (GenICam)
            app.camera = EBCamera("gentl", 1, "Mono12");

            % init valve (NI instrument controller)
            app.valves = EBValves();
        end


        function CloseEasyBehaviourSystem(app)
            EasyBehaviourUIFigureCloseRequest(app, []);
        end
    end

    methods(Static)
        function DeployParallelConfig()

        end

        function r = ValidateExternalPackages()
            % This function validate external packages
            pinfo = struct2table(ver);
            par_flag = any(string(pinfo.Name).contains(...
                "Parallel Computing Toolbox"));
            img_flag = any(string(pinfo.Name).contains(...
                "Image Acquisition Toolbox"));
            daq_flag = any(string(pinfo.Name).contains(...
                "Data Acquisition Toolbox"));

            if ~img_flag
                warning("ValidateExternalPackage:toolboxNotInstall", ...
                    "Necessary toolbox: Image Acquisition Toolbox is not installed.");
            end
            if ~daq_flag
                warning("ValidateExternalPackage:toolboxNotInstall", ...
                    "Necessary toolbox: Data Acquisition Toolbox is not installed.");
            end
            if ~par_flag
                warning("ValidateExternalPackage:toolboxNotInstall", ...
                    "Necessary toolbox: Parallel Computing Toolbox is not installed.");
            end

            r = (img_flag && daq_flag && par_flag);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, debug_flag)
            
        end

        % Menu selected function: Menu_NewExperiment
        function Menu_NewExperimentSelected(app, event)
            
        end

        % Menu selected function: Menu_Quit
        function Menu_QuitSelected(app, event)
            CloseEasyBehaviourSystem(app);
        end

        % Menu selected function: Menu_Document
        function Menu_DocumentSelected(app, event)
            
        end

        % Menu selected function: Menu_About
        function Menu_AboutSelected(app, event)
            msg = sprintf(['Easy Larvae Behaviour \n' ...
                'Larvae odors-behaviour analysis system \n' ...
                'Version: 1.0 (Archimedes) debug @si-lab \n' ...
                'Release Date: 2024/11/04 \n' ...
                'Author: Weihan Li \n' ...
                'E-mail: liweihan20@mails.ucas.ac.cn']);
            uialert(app.EasyBehaviourUIFigure, msg, "消息", "Icon","info");
        end

        % Close request function: EasyBehaviourUIFigure
        function EasyBehaviourUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Key press function: EasyBehaviourUIFigure
        function EasyBehaviourUIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case "return"
                    % start running
                case "escape"
                    % abort the experiment
                case "space"
                    % pause/recover experiment
                otherwise
            end
        end

        % Menu selected function: Menu_Parameterization
        function Menu_ParameterizationSelected(app, event)
            % Parameterization：
            % 参数化：选择图像前景的(时空)局部运动特征
            % [运动特征]：
            %   - 运动速度
            %   - 加速度
            %   - ...
            % 提供自定义选项：自定义函数 U(S,F,T)->u, S: 前景像素(强度&坐标)集, 
            % F: 前景梯度算子(滤波器：分割像素集)， T: 时间窗口
            % u: 大小由F定义的向量，数值为U定义的运动特征
        end

        % Menu selected function: Menu_BrightnessAndContrast
        function Menu_BrightnessAndContrastSelected(app, event)
            
        end

        % Menu selected function: Menu_Scale
        function Menu_ScaleSelected(app, event)
            
        end

        % Menu selected function: Menu_Peripherals
        function Menu_PeripheralsSelected(app, event)
            HWC(app);
        end

        % Menu selected function: Menu_Preference
        function Menu_PreferenceSelected(app, event)
            
        end

        % Menu selected function: Menu_Tracker
        function Menu_TrackerSelected(app, event)
            
        end

        % Menu selected function: Menu_FeatureAnalysis
        function Menu_FeatureAnalysisSelected(app, event)
            
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            
        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            
        end

        % Menu selected function: Menu_Update
        function Menu_UpdateSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EasyBehaviourUIFigure = uifigure('Visible', 'off');
            app.EasyBehaviourUIFigure.Name = 'Easy Behaviour';
            app.EasyBehaviourUIFigure.CloseRequestFcn = createCallbackFcn(app, @EasyBehaviourUIFigureCloseRequest, true);
            app.EasyBehaviourUIFigure.KeyPressFcn = createCallbackFcn(app, @EasyBehaviourUIFigureKeyPress, true);
            app.Menu_File = uimenu(app.EasyBehaviourUIFigure);
            app.Menu_File.Text = '文件';
            app.Menu_NewExperiment = uimenu(app.Menu_File);
            app.Menu_NewExperiment.MenuSelectedFcn = createCallbackFcn(app, @Menu_NewExperimentSelected, true);
            app.Menu_NewExperiment.Enable = 'off';
            app.Menu_NewExperiment.Text = '新实验';
            app.Menu_Quit = uimenu(app.Menu_File);
            app.Menu_Quit.MenuSelectedFcn = createCallbackFcn(app, @Menu_QuitSelected, true);
            app.Menu_Quit.Text = '退出';
            app.Menu_Image = uimenu(app.EasyBehaviourUIFigure);
            app.Menu_Image.Text = '图像';
            app.Menu_BrightnessAndContrast = uimenu(app.Menu_Image);
            app.Menu_BrightnessAndContrast.MenuSelectedFcn = createCallbackFcn(app, @Menu_BrightnessAndContrastSelected, true);
            app.Menu_BrightnessAndContrast.Enable = 'off';
            app.Menu_BrightnessAndContrast.Text = '亮度/对比度';
            app.Menu_Scale = uimenu(app.Menu_Image);
            app.Menu_Scale.MenuSelectedFcn = createCallbackFcn(app, @Menu_ScaleSelected, true);
            app.Menu_Scale.Enable = 'off';
            app.Menu_Scale.Text = '比例/尺';
            app.Menu_Configuration = uimenu(app.EasyBehaviourUIFigure);
            app.Menu_Configuration.Text = '配置';
            app.Menu_Peripherals = uimenu(app.Menu_Configuration);
            app.Menu_Peripherals.MenuSelectedFcn = createCallbackFcn(app, @Menu_PeripheralsSelected, true);
            app.Menu_Peripherals.Text = '外部设备';
            app.Menu_Preference = uimenu(app.Menu_Configuration);
            app.Menu_Preference.MenuSelectedFcn = createCallbackFcn(app, @Menu_PreferenceSelected, true);
            app.Menu_Preference.Text = '首选项';
            app.Menu_Tool = uimenu(app.EasyBehaviourUIFigure);
            app.Menu_Tool.Text = '工具';
            app.Menu_Tracker = uimenu(app.Menu_Tool);
            app.Menu_Tracker.MenuSelectedFcn = createCallbackFcn(app, @Menu_TrackerSelected, true);
            app.Menu_Tracker.Enable = 'off';
            app.Menu_Tracker.Text = '追踪器';
            app.Menu_Parameterization = uimenu(app.Menu_Tool);
            app.Menu_Parameterization.MenuSelectedFcn = createCallbackFcn(app, @Menu_ParameterizationSelected, true);
            app.Menu_Parameterization.Enable = 'off';
            app.Menu_Parameterization.Text = '参数化';
            app.Menu_FeatureAnalysis = uimenu(app.Menu_Tool);
            app.Menu_FeatureAnalysis.MenuSelectedFcn = createCallbackFcn(app, @Menu_FeatureAnalysisSelected, true);
            app.Menu_FeatureAnalysis.Enable = 'off';
            app.Menu_FeatureAnalysis.Text = '特征分析';
            app.Menu_Plugins = uimenu(app.EasyBehaviourUIFigure);
            app.Menu_Plugins.Enable = 'off';
            app.Menu_Plugins.Text = '插件';
            app.Menu_Help = uimenu(app.EasyBehaviourUIFigure);
            app.Menu_Help.Text = '帮助';
            app.Menu_Language = uimenu(app.Menu_Help);
            app.Menu_Language.Text = '语言';
            app.Menu = uimenu(app.Menu_Language);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Checked = 'on';
            app.Menu.Text = '(简体)中文';
            app.EnglishMenu = uimenu(app.Menu_Language);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Enable = 'off';
            app.EnglishMenu.Text = 'English';
            app.Menu_Document = uimenu(app.Menu_Help);
            app.Menu_Document.MenuSelectedFcn = createCallbackFcn(app, @Menu_DocumentSelected, true);
            app.Menu_Document.Enable = 'off';
            app.Menu_Document.Text = '文档';
            app.Menu_About = uimenu(app.Menu_Help);
            app.Menu_About.MenuSelectedFcn = createCallbackFcn(app, @Menu_AboutSelected, true);
            app.Menu_About.Text = '关于';
            app.Menu_Update = uimenu(app.Menu_Help);
            app.Menu_Update.MenuSelectedFcn = createCallbackFcn(app, @Menu_UpdateSelected, true);
            app.Menu_Update.Enable = 'off';
            app.Menu_Update.Text = '检查更新';
            app.GridLayout = uigridlayout(app.EasyBehaviourUIFigure);
            app.UIAxes_TaskViewer = uiaxes(app.GridLayout);
            app.UIAxes_TaskViewer.XTick = [];
            app.UIAxes_TaskViewer.YTick = [];
            app.GridLayout_Progress = uigridlayout(app.GridLayout);
            app.Label_Progress = uilabel(app.GridLayout_Progress);
            app.Label_Progress.Text = '';
            app.Label_ProgressLeft = uilabel(app.GridLayout_Progress);
            app.Label_ProgressLeft.Text = '';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '剩余时间';
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = '23:59:59';
            app.NILabel = uilabel(app.GridLayout2);
            app.NILabel.Text = '设备';
            app.Lamp_Equipment = uilamp(app.GridLayout2);
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '实验';
            app.Lamp_Experiment = uilamp(app.GridLayout2);
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '控制系统已就绪。';
            % Show the figure after all components are created
            app.EasyBehaviourUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EasyBehaviour(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EasyBehaviourUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EasyBehaviourUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EasyBehaviourUIFigure)
        end
    end
end