classdef EBVideoPlayer < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout           matlab.ui.container.GridLayout
        Slider_Time          matlab.ui.control.Slider
        GridLayout2          matlab.ui.container.GridLayout
        Button_Play          matlab.ui.control.Button
        GridLayout4          matlab.ui.container.GridLayout
        Button_SlowDown      matlab.ui.control.Button
        Button_Setting       matlab.ui.control.Button
        GridLayout3          matlab.ui.container.GridLayout
        Button_CurrentFrame  matlab.ui.control.Button
        Button_SpeedUp       matlab.ui.control.Button
        UIAxes_Viewer        matlab.ui.control.UIAxes
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        withDetectBox (1,1) logical = false;
        withMetadata (1,1) logical = true;
        withPlaySpeed (1,1) logical = true;
        withScaleBar (1,1) logical = false;
        withTimeStamp (1,1) logical = true;
        withTrajectory (1,1) logical = false;
    end
    
    properties (Access = public)
        Connect (1,1) logical = false;
        Enable (1,1) logical = false;
    end
    

    properties (Constant, Hidden)
        TIMESTAMP_OFFSET_X = 10         % relative to left border
        TIMESTAMP_OFFSET_Y = 10         % relative to top border
        SCALE_OFFSET_X = 10             % relative to left border
        SCALE_OFFSET_Y = 10             % relative to bottom border
        SPEED_OFFSET_X = 10             % relative to right border
        SPEED_OFFSET_Y = 10             % relative to top border
        METADATA_OFFSET_X = 10          % relative to right border
        METADATA_OFFSET_Y = 10          % relative to bottom border
        INIT_SCREEN_SIZE = [512, 272]   % initialized [width, height]
    end

    properties (Access = public)
        ScaleBarLength = 1          % scale bar length in world coordinate system
    end

    properties (Access = private)
        kernel      (1,1)               % EBKernel object
        pConfig     (1,1)               % PlayerConfiguration object
        player      (1,1)   timer       % timer for data acqusition from kernel
        hImage      (1,1)               % handle of image, matlab.graphics.primitive.Image
        hTime       (1,1)               % handle of time text, matlab.graphics.primitive.Text
        hScale      (1,1)   struct      % struct with [scale line handle, scale text handle]
        hSpeed      (1,1)               % handle of play speed text, matlab.graphics.primitive.Text
        hMeta       (1,1)               % handle of meta data text, matlab.graphics.primitive.Text
        hBoxes      (1,1)               % 1-by-1 patch object with some polygons
        hTrace      (1,1)   dictionary  % store trajectory, <string> |-> <cell>, id -> {line, color}
        video       (1,1)               % 1-by-1 EBVideo object, store required video frames
        autoPlay    (1,1)   logical     % 1-by-1 auto play flag, determine if image update autometically
        screen_size (1,2)   double      % real screen size
    end

    methods
        function delete(comp)
            stop(comp.player);
            
            delete(comp.player);

            warning("on", 'EBVideo:emptyVideo');
        end
    end

    methods (Access = ?PlayerConfiguration)

        function SetTimeStampState(comp, state)
            arguments
                comp
                state   (1,1)   logical 
            end
            comp.withTimeStamp = state;
        end

        function SetScaleBarState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withScaleBar = state;
        end

        function SetPlayerSpeedState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withPlaySpeed = state;
        end

        function SetMetadataState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withMetadata = state;
        end

        function SetDetectBoxState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withDetectBox = state;
        end

        function SetTrajectoryState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withTrajectory = state;
        end
    end

    methods (Access = ?EasyBehaviour)
        function SetKernel(comp, ker)
            arguments
                comp
                ker     (1,1)   EBKernel
            end

            % update kernel
            comp.kernel = ker;
        end

        function UpdateViewer(comp)
            % update screen size
            UpdateScreenSize(comp);

            % update add-ons location
            UpdateAddonsLocation(comp);

            drawnow
        end
    end

    methods (Access = private)
        function UpdateAppearence(comp, src, evt)
            src; %#ok<VUNUS>
            evt; %#ok<VUNUS>

            if isobject(comp.kernel) ...
                    && isvalid(comp.kernel) ...
                    && comp.kernel.IsRunning

                %% Acquire Data
                % get current video frame
                vf = comp.kernel.CurrentVideoFrame; % EBVideoFrame object
                cvf = comp.video.GetLastFrame();

                if isempty(vf) || isequal(vf, cvf)
                    % same object, kernel busy
                    return;
                else
                    % push into video queue
                    comp.video.AddFrame(vf);

                    %% Update Screen and Time(Frame)
                    if comp.autoPlay
                        UpdateScreen(comp, vf);

                        UpdateProcessBar(comp);

                        drawnow
                    end
                end
            end
        end


        function UpdateScreen(comp, vf)
            % screen defination as followed:
            %% ======================== Screen ============================
            %  [TIME]                                        [Play Speed] %
            %                                                             %
            %                      [*]                                    %
            %                      Video Frame Image Data                 %
            %                                [>]                          %
            %              [<]                         [*]                %
            %                                                             %
            %  [Scale]                                        [Meta Data] %
            %% ============================================================

            if ~isempty(vf)
                % display image
                comp.hImage.CData = vf.ImageData;

                % selective drawing with tag (checked)
                if comp.withTimeStamp
                    comp.hTime.Visible = "on";
                    comp.hTime.String = ...
                        string(duration(seconds(vf.TimeStamp),"Format","hh:mm:ss.S"));
                end

                if comp.withScaleBar
                    if vf.Scale.resUnit == "um"
                        sr = 1e-4;
                    elseif vf.Scale.resUnit == "mm"
                        sr = 0.1;
                    elseif vf.Scale.resUnit == "cm"
                        sr = 1;
                    elseif vf.Scale.resUnit == "inch"
                        sr = 2.54;
                    end
                    DX = comp.ScaleBarLength / vf.Scale.xRes * sr;  % in pixels
                    comp.hScale.seglin.Visible = "on";
                    comp.hScale.seglin.XData = [comp.SCALE_OFFSET_X, ...
                                                comp.SCALE_OFFSET_X + DX];
                    comp.hScale.txt.Visible = "on";
                    comp.hScale.txt.String = sprintf("%.1f %s", ...
                        comp.ScaleBarLength, vf.Scale.resUnit);
                    comp.hScale.txt.Position(1) = comp.SCALE_OFFSET_X + DX/2;
                end

                if comp.withPlaySpeed

                end

                if comp.withMetadata
                    comp.hMeta.String = vf.MetaData;
                    comp.hMeta.Visible = "on";
                end

                if comp.withDetectBox
                    
                end

                if comp.withTrajectory

                end

            end
        end

        function UpdateProcessBar(comp)
            nf = numel(comp.video);
            if nf <= 1
                comp.Slider_Time.Enable = "off";
            else
                comp.Slider_Time.Enable = "on";
                comp.Slider_Time.Limits = [1, nf];
                comp.Slider_Time.MinorTicks = 1:nf;
                comp.Slider_Time.Value = nf;
            end
        end

        function UpdateScreenSize(comp)
            comp.screen_size = [comp.kernel.Devices{"camera"}.ROIWidth, ...
                                comp.kernel.Devices{"camera"}.ROIHeight];
            comp.UIAxes_Viewer.XLim = [0.5, comp.screen_size(1) + 0.5];
            comp.UIAxes_Viewer.YLim = [0.5, comp.screen_size(2) + 0.5];
            comp.hImage.CData = zeros(comp.screen_size([2,1]));
        end

        function UpdateAddonsLocation(comp)
            % scale line
            comp.hScale.seglin.YData = [comp.screen_size(2) - comp.SCALE_OFFSET_Y, ...
                                        comp.screen_size(2) - comp.SCALE_OFFSET_Y];

            % scale txt
            comp.hScale.txt.Position(2) = comp.screen_size(2)-comp.SCALE_OFFSET_Y + 6;

            % play speed
            comp.hSpeed.Position(1) = comp.screen_size(1) - comp.SPEED_OFFSET_X;

            % meta data
            comp.hMeta.Position(1) = comp.screen_size(1) - comp.METADATA_OFFSET_X;
            comp.hMeta.Position(2) = comp.screen_size(2) - comp.METADATA_OFFSET_Y;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % init variables
            comp.video = EBVideo.empty();
            comp.autoPlay = true;

            % init viewer
            comp.UIAxes_Viewer.Visible = "off";
            comp.UIAxes_Viewer.Toolbar = [];
            disableDefaultInteractivity(comp.UIAxes_Viewer);
            comp.UIAxes_Viewer.XLim = [0.5, comp.INIT_SCREEN_SIZE(1)+0.5];
            comp.UIAxes_Viewer.YLim = [0.5, comp.INIT_SCREEN_SIZE(2)+0.5];
            % adjust aspect ratio
            pbaspect(comp.UIAxes_Viewer, [comp.INIT_SCREEN_SIZE(1), ...
                                          comp.INIT_SCREEN_SIZE(2),...
                                          1]);

            % init add-ons
            % time stamp
            comp.hTime = text(comp.UIAxes_Viewer, ...
                              comp.TIMESTAMP_OFFSET_X, ...
                              comp.TIMESTAMP_OFFSET_Y, ...
                              "00:00:00.0", "FontSize", 12, ...
                              "Color", "y", "FontWeight","bold",...
                              "HorizontalAlignment","left", ...
                              "VerticalAlignment","top", ...
                              "Visible","off");

            % scale line
            XX = [comp.SCALE_OFFSET_X, comp.SCALE_OFFSET_X + 10];
            YY = [comp.INIT_SCREEN_SIZE(2)-comp.SCALE_OFFSET_Y, ...
                  comp.INIT_SCREEN_SIZE(2)-comp.SCALE_OFFSET_Y];
            comp.hScale.seglin = line(comp.UIAxes_Viewer, ...
                                      XX, YY, ...
                                      "Color","w", ...
                                      "LineWidth",1, ...
                                      "Marker","|", ...
                                      "MarkerSize", 5, ...
                                      "Visible", "off");
            % scale txt
            comp.hScale.txt = text(comp.UIAxes_Viewer, ...
                                   comp.SCALE_OFFSET_X + 5, ...
                                   comp.INIT_SCREEN_SIZE(2)-comp.SCALE_OFFSET_Y + 6, ...
                                   "1 cm", "FontSize",12, ...
                                   "Color","w", "FontWeight","bold", ...
                                   "HorizontalAlignment","center", ...
                                   "VerticalAlignment","bottom", ...
                                   "Visible","off");

            % play speed
            if comp.withPlaySpeed
                comp.hSpeed = text(comp.UIAxes_Viewer, ...
                                   comp.INIT_SCREEN_SIZE(1) - comp.SPEED_OFFSET_X, ...
                                   comp.SPEED_OFFSET_Y, ...
                                   "Play@0.05X", "FontSize",12, ...
                                   "Color","y", "FontWeight","normal", ...
                                   "HorizontalAlignment","right", ...
                                   "VerticalAlignment","top", ...
                                   "Visible","off");
            end

            % meta data
            if comp.withMetadata
                comp.hMeta = text(comp.UIAxes_Viewer, ...
                                  comp.INIT_SCREEN_SIZE(1) - comp.METADATA_OFFSET_X, ...
                                  comp.INIT_SCREEN_SIZE(2) - comp.METADATA_OFFSET_Y, ...
                                  "Code: ", "FontSize",12, ...
                                  "Color","w", "FontWeight","bold", ...
                                  "HorizontalAlignment", "right", ...
                                  "VerticalAlignment", "bottom", ...
                                  "Visible","off");
            end

            % detect boxes
            if comp.withDetectBox
                comp.hBoxes = patch(comp.UIAxes_Viewer, ...
                                    [0;0;1;1], ...
                                    [1;0;0;1], ...
                                    "EdgeColor", "green", ...
                                    "FaceColor", "none", ...
                                    "LineWidth", 1, ...
                                    "Visible", "off");
            end

            % trajectory
            if comp.withTrajectory
                comp.hTrace = dictionary();  % empty, wait for new trace
            end

            % !!! HOLD ONNNNN
            hold(comp.UIAxes_Viewer, "on");

            % init timer
            comp.player = timer("BusyMode",         "drop",...
                                "ExecutionMode",    "fixedRate", ...
                                "Name",             "VideoPlayer_Agent", ...
                                "Period",           0.1, ...
                                "StartDelay",       0,...
                                "TasksToExecute",   inf, ...
                                "TimerFcn",         @comp.UpdateAppearence);

            % disable warnings
            warning("off", 'EBVideo:emptyVideo');
        end

        % Button pushed function: Button_Setting
        function Button_SettingPushed(comp, event)
            if ~isobject(comp.pConfig) || ~isvalid(comp.pConfig)
                comp.pConfig = PlayerConfiguration(comp);
                comp.pConfig.GetOptions(comp.withTimeStamp, ...
                                        comp.withScaleBar, ...
                                        comp.withPlaySpeed, ...
                                        comp.withMetadata, ...
                                        comp.withDetectBox, ...
                                        comp.withTrajectory);
            end
        end

        % Button pushed function: Button_SlowDown
        function Button_SlowDownPushed(comp, event)
            
        end

        % Button pushed function: Button_Play
        function Button_PlayPushed(comp, event)
            
        end

        % Button pushed function: Button_SpeedUp
        function Button_SpeedUpPushed(comp, event)
            
        end

        % Button pushed function: Button_CurrentFrame
        function Button_CurrentFramePushed(comp, event)
            % locate at last frame
            comp.Slider_Time.Value = numel(comp.video); 

            % play the last frame
            UpdateScreen(comp, comp.video.GetLastFrame());
            
            % auto play enable
            comp.autoPlay = true;

            drawnow
        end

        % Value changing function: Slider_Time
        function Slider_TimeValueChanging(comp, event)
            % manual replay
            comp.autoPlay = false;
            
            frameIndex = round(event.Value);
            comp.Slider_Time.Value = frameIndex;  % integer location

            vf = comp.video.GetFrame(frameIndex);

            UpdateScreen(comp, vf);

            drawnow
        end
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % Enable / Disable
            comp.Button_SlowDown.Enable = comp.Enable;
            comp.Button_Play.Enable = comp.Enable;
            comp.Button_SpeedUp.Enable = comp.Enable;
            comp.Button_CurrentFrame.Enable = comp.Enable;
            comp.Slider_Time.Enable = comp.Enable;

            if ~comp.Enable, comp.Slider_Time.Limits = [0, 1]; end
            
            if comp.Enable
                if ~comp.Connect
                    comp.hImage = image(comp.UIAxes_Viewer, zeros(comp.INIT_SCREEN_SIZE([2,1])));
                    colormap(comp.UIAxes_Viewer, "gray");
                    % hold(comp.UIAxes_Viewer, "on");
                else
                    % enable but disconnect, do nothing(keep screen)
                end
            else
                % disable, remove screen
                hold(comp.UIAxes_Viewer, "off");
                if isobject(comp.hImage), delete(comp.hImage); end
                comp.UIAxes_Viewer.Visible = "off";
            end

            % Switch timer
            if comp.Enable && comp.Connect
                if ~isequal("on", comp.player.Running)
                    start(comp.player);
                end
            else
                stop(comp.player);
            end
        end

        % 创建底层组件
        function setup(comp)

            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            comp.Position = [1 1 532 392];
            comp.BackgroundColor = [0.94 0.94 0.94];

            % Create GridLayout
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout.ColumnWidth = {'1x'};
            comp.GridLayout.RowHeight = {'1x', 40, 40};

            % Create UIAxes_Viewer
            comp.UIAxes_Viewer = uiaxes(comp.GridLayout);
            title(comp.UIAxes_Viewer, '相机图像(实时)')
            comp.UIAxes_Viewer.Toolbar.Visible = 'off';
            comp.UIAxes_Viewer.XTick = [];
            comp.UIAxes_Viewer.YTick = [];
            comp.UIAxes_Viewer.FontSize = 14;
            comp.UIAxes_Viewer.Layout.Row = 1;
            comp.UIAxes_Viewer.Layout.Column = 1;
            comp.UIAxes_Viewer.Visible = 'off';

            % Create GridLayout2
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.GridLayout2.ColumnWidth = {'1x', 50, '1x'};
            comp.GridLayout2.RowHeight = {'1x'};
            comp.GridLayout2.Padding = [0 0 0 0];
            comp.GridLayout2.Layout.Row = 3;
            comp.GridLayout2.Layout.Column = 1;

            % Create GridLayout3
            comp.GridLayout3 = uigridlayout(comp.GridLayout2);
            comp.GridLayout3.ColumnWidth = {50, '1x', 50};
            comp.GridLayout3.RowHeight = {'1x'};
            comp.GridLayout3.Padding = [0 0 0 0];
            comp.GridLayout3.Layout.Row = 1;
            comp.GridLayout3.Layout.Column = 3;

            % Create Button_SpeedUp
            comp.Button_SpeedUp = uibutton(comp.GridLayout3, 'push');
            comp.Button_SpeedUp.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_SpeedUpPushed, true);
            comp.Button_SpeedUp.Icon = fullfile(pathToMLAPP, 'sources', 'speed_up.png');
            comp.Button_SpeedUp.Layout.Row = 1;
            comp.Button_SpeedUp.Layout.Column = 1;
            comp.Button_SpeedUp.Text = '';

            % Create Button_CurrentFrame
            comp.Button_CurrentFrame = uibutton(comp.GridLayout3, 'push');
            comp.Button_CurrentFrame.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_CurrentFramePushed, true);
            comp.Button_CurrentFrame.Icon = fullfile(pathToMLAPP, 'sources', 'current.png');
            comp.Button_CurrentFrame.Layout.Row = 1;
            comp.Button_CurrentFrame.Layout.Column = 3;
            comp.Button_CurrentFrame.Text = '';

            % Create GridLayout4
            comp.GridLayout4 = uigridlayout(comp.GridLayout2);
            comp.GridLayout4.ColumnWidth = {50, '1x', 50};
            comp.GridLayout4.RowHeight = {'1x'};
            comp.GridLayout4.Padding = [0 0 0 0];
            comp.GridLayout4.Layout.Row = 1;
            comp.GridLayout4.Layout.Column = 1;

            % Create Button_Setting
            comp.Button_Setting = uibutton(comp.GridLayout4, 'push');
            comp.Button_Setting.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_SettingPushed, true);
            comp.Button_Setting.Icon = fullfile(pathToMLAPP, 'sources', 'setting.png');
            comp.Button_Setting.Layout.Row = 1;
            comp.Button_Setting.Layout.Column = 1;
            comp.Button_Setting.Text = '';

            % Create Button_SlowDown
            comp.Button_SlowDown = uibutton(comp.GridLayout4, 'push');
            comp.Button_SlowDown.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_SlowDownPushed, true);
            comp.Button_SlowDown.Icon = fullfile(pathToMLAPP, 'sources', 'slow_down.png');
            comp.Button_SlowDown.Layout.Row = 1;
            comp.Button_SlowDown.Layout.Column = 3;
            comp.Button_SlowDown.Text = '';

            % Create Button_Play
            comp.Button_Play = uibutton(comp.GridLayout2, 'push');
            comp.Button_Play.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_PlayPushed, true);
            comp.Button_Play.Icon = fullfile(pathToMLAPP, 'sources', 'play.png');
            comp.Button_Play.Layout.Row = 1;
            comp.Button_Play.Layout.Column = 2;
            comp.Button_Play.Text = '';

            % Create Slider_Time
            comp.Slider_Time = uislider(comp.GridLayout);
            comp.Slider_Time.Limits = [0 60];
            comp.Slider_Time.MajorTicks = [0 10 20 30 40 50 60];
            comp.Slider_Time.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @Slider_TimeValueChanging, true);
            comp.Slider_Time.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60];
            comp.Slider_Time.Layout.Row = 2;
            comp.Slider_Time.Layout.Column = 1;
            
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end